classdef AldyGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunningLampLabel                matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        JoggingEnabledLampLabel         matlab.ui.control.Label
        JoggingEnabledLamp              matlab.ui.control.Lamp
        eStopButton                     matlab.ui.control.Button
        ModeSwitchLabel                 matlab.ui.control.Label
        ModeSwitch                      matlab.ui.control.ToggleSwitch
        L1Slider_UR3                    matlab.ui.control.Slider
        L2Slider_UR3                    matlab.ui.control.Slider
        L3Slider_UR3                    matlab.ui.control.Slider
        L4Slider_UR3                    matlab.ui.control.Slider
        L5Slider_UR3                    matlab.ui.control.Slider
        L6Slider_UR3                    matlab.ui.control.Slider
        YplusButton_UR3                 matlab.ui.control.Button
        XminusButton_UR3                matlab.ui.control.Button
        XplusButton_UR3                 matlab.ui.control.Button
        YminusButton_UR3                matlab.ui.control.Button
        ZplusButton_UR3                 matlab.ui.control.Button
        ZminusButton_UR3                matlab.ui.control.Button
        UR3RobotTransformLabel          matlab.ui.control.Label
        YLabel_UR3                      matlab.ui.control.Label
        XLabel_UR3                      matlab.ui.control.Label
        ZLabel_UR3                      matlab.ui.control.Label
        robotTransformY_UR3             matlab.ui.control.Label
        robotTransformX_UR3             matlab.ui.control.Label
        robotTransformZ_UR3             matlab.ui.control.Label
        PitchLabel_UR3                  matlab.ui.control.Label
        RollLabel_UR3                   matlab.ui.control.Label
        YawLabel_UR3                    matlab.ui.control.Label
        robotTransformPitch_UR3         matlab.ui.control.Label
        robotTransformRoll_UR3          matlab.ui.control.Label
        robotTransformYaw_UR3           matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        L1plusButton_UR3                matlab.ui.control.Button
        L1minusButton_UR3               matlab.ui.control.Button
        L2plusButton_UR3                matlab.ui.control.Button
        L2minusButton_UR3               matlab.ui.control.Button
        L3plusButton_UR3                matlab.ui.control.Button
        L3minusButton_UR3               matlab.ui.control.Button
        L4plusButton_UR3                matlab.ui.control.Button
        L4minusButton_UR3               matlab.ui.control.Button
        L5plusButton_UR3                matlab.ui.control.Button
        L5minusButton_UR3               matlab.ui.control.Button
        L6plusButton_UR3                matlab.ui.control.Button
        L6minusButton_UR3               matlab.ui.control.Button
        LinkJogStepdegEditFieldLabel    matlab.ui.control.Label
        LinkJogStepdegEditField_UR3     matlab.ui.control.NumericEditField
        XYZJogStepmmEditFieldLabel      matlab.ui.control.Label
        XYZJogStepmmEditField_UR3       matlab.ui.control.NumericEditField
        personYplusButton               matlab.ui.control.Button
        personXminusButton              matlab.ui.control.Button
        personXplusButton               matlab.ui.control.Button
        personYminusButton              matlab.ui.control.Button
        PersonControlsLabel             matlab.ui.control.Label
        PersonStepmmEditFieldLabel      matlab.ui.control.Label
        PersonStepmmEditField           matlab.ui.control.NumericEditField
        LightCurtainSwitchLabel         matlab.ui.control.Label
        LightCurtainSwitch              matlab.ui.control.Switch
        L1Slider_KUKA                   matlab.ui.control.Slider
        L2Slider_KUKA                   matlab.ui.control.Slider
        L3Slider_KUKA                   matlab.ui.control.Slider
        L4Slider_KUKA                   matlab.ui.control.Slider
        L5Slider_KUKA                   matlab.ui.control.Slider
        L6Slider_KUKA                   matlab.ui.control.Slider
        YplusButton_KUKA                matlab.ui.control.Button
        XminusButton_KUKA               matlab.ui.control.Button
        XplusButton_KUKA                matlab.ui.control.Button
        YminusButton_KUKA               matlab.ui.control.Button
        ZplusButton_KUKA                matlab.ui.control.Button
        ZminusButton_KUKA               matlab.ui.control.Button
        KUKARobotTransformLabel         matlab.ui.control.Label
        YLabel_KUKA                     matlab.ui.control.Label
        XLabel_KUKA                     matlab.ui.control.Label
        ZLabel_KUKA                     matlab.ui.control.Label
        robotTransformY_KUKA            matlab.ui.control.Label
        robotTransformX_KUKA            matlab.ui.control.Label
        robotTransformZ_KUKA            matlab.ui.control.Label
        PitchLabel_KUKA                 matlab.ui.control.Label
        RollLabel_KUKA                  matlab.ui.control.Label
        YawLabel_KUKA                   matlab.ui.control.Label
        robotTransformPitch_KUKA        matlab.ui.control.Label
        robotTransformRoll_KUKA         matlab.ui.control.Label
        robotTransformYaw_KUKA          matlab.ui.control.Label
        L1plusButton_KUKA               matlab.ui.control.Button
        L1minusButton_KUKA              matlab.ui.control.Button
        L2plusButton_KUKA               matlab.ui.control.Button
        L2minusButton_KUKA              matlab.ui.control.Button
        L3plusButton_KUKA               matlab.ui.control.Button
        L3minusButton_KUKA              matlab.ui.control.Button
        L4plusButton_KUKA               matlab.ui.control.Button
        L4minusButton_KUKA              matlab.ui.control.Button
        L5plusButton_KUKA               matlab.ui.control.Button
        L5minusButton_KUKA              matlab.ui.control.Button
        L6plusButton_KUKA               matlab.ui.control.Button
        L6minusButton_KUKA              matlab.ui.control.Button
        LinkJogStepdegEditField_2Label  matlab.ui.control.Label
        LinkJogStepdegEditField_KUKA    matlab.ui.control.NumericEditField
        XYZJogStepmmEditField_2Label    matlab.ui.control.Label
        XYZJogStepmmEditField_KUKA      matlab.ui.control.NumericEditField
        L7Slider_KUKA                   matlab.ui.control.Slider
        L7plusButton_KUKA               matlab.ui.control.Button
        L7minusButton_KUKA              matlab.ui.control.Button
    end

    
    properties (Access = private)
        idle = true;
        eStop = false;
        
        bot1P;
        bot1T;
        
        bot2P;
        bot2T;
        
        env;
    end
    
    methods (Access = public)
        
        function app = updateGUI(app, env)
            app.env = env;
            if app.env.eStop == true
                app.eStopButton.BackgroundColor = [1,0,0];
                app.eStop = true;
                app.idle = true;
            else
                app.eStopButton.BackgroundColor = [0,1,0];
                app.eStop = false;
            end
            
            %update gui transform variables from env
            app.bot1P = app.env.AldyBaggerBotUR3.robot.model.getpos;
            app.bot1T = app.env.AldyBaggerBotUR3.robot.model.fkine(app.bot1P);
            app.bot2P = app.env.AldyBaggerBotKUKA.robot.model.getpos;
            app.bot2T = app.env.AldyBaggerBotKUKA.robot.model.fkine(app.bot2P);
            
            app.robotTransformX_UR3.Text = num2str(app.bot1T(1, 4));
            app.robotTransformY_UR3.Text = num2str(app.bot1T(2, 4));
            app.robotTransformZ_UR3.Text = num2str(app.bot1T(3, 4));
            app.robotTransformX_KUKA.Text = num2str(app.bot2T(1, 4));
            app.robotTransformY_KUKA.Text = num2str(app.bot2T(2, 4));
            app.robotTransformZ_KUKA.Text = num2str(app.bot2T(3, 4));
            
            rpy = rad2deg(tr2rpy(app.bot1T));
            app.robotTransformRoll_UR3.Text = num2str(rpy(1));
            app.robotTransformPitch_UR3.Text = num2str(rpy(2));
            app.robotTransformYaw_UR3.Text = num2str(rpy(3));
            
            rpy = rad2deg(tr2rpy(app.bot2T));
            app.robotTransformRoll_KUKA.Text = num2str(rpy(1));
            app.robotTransformPitch_KUKA.Text = num2str(rpy(2));
            app.robotTransformYaw_KUKA.Text = num2str(rpy(3));
            
            %update slider positions
            pDegrees = rad2deg(app.bot1P);
            app.L1Slider_UR3.Value = pDegrees(1);
            app.L2Slider_UR3.Value = pDegrees(2);
            app.L3Slider_UR3.Value = pDegrees(3);
            app.L4Slider_UR3.Value = pDegrees(4);
            app.L5Slider_UR3.Value = pDegrees(5);
            app.L6Slider_UR3.Value = pDegrees(6);
            
            pDegrees = rad2deg(app.bot2P);
            app.L1Slider_KUKA.Value = pDegrees(1);
            app.L2Slider_KUKA.Value = pDegrees(2);
            app.L3Slider_KUKA.Value = pDegrees(3);
            app.L4Slider_KUKA.Value = pDegrees(4);
            app.L5Slider_KUKA.Value = pDegrees(5);
            app.L6Slider_KUKA.Value = pDegrees(6);
            app.L7Slider_KUKA.Value = pDegrees(7);
            
            %update status lamps
            if env.idle == true
                app.RunningLamp.Color = [1,0,0];
            else
                app.RunningLamp.Color = [0,1,0];
                app.JoggingEnabledLamp.Color = [1,0,0];
            end
            
        end
        
        function env = updateEnv(app, env)
            env.idle = app.idle;
            env.eStop = app.eStop;
            try app.LightCurtainSwitch.Value == 'Enabled';
                env.lightCurtainEnabled = true;
            catch
                env.lightCurtainEnabled = false;
            end
            
            
            env.Person.body.base = app.env.Person.body.base;
            env.Person.body.animate(0);
            
            
            if app.eStop == false
                if app.idle == true
                    %jog mode
                    app.JoggingEnabledLamp.Color = [0,1,0];
                    
                    %sliders
                    p = [app.L1Slider_UR3.Value, app.L2Slider_UR3.Value, app.L3Slider_UR3.Value, app.L4Slider_UR3.Value, app.L5Slider_UR3.Value, app.L6Slider_UR3.Value];
                    p = deg2rad(p);
                    env.AldyBaggerBotUR3.robot.model.animate(p);
                    env.AldyBaggerBotUR3.gripper.moveGripper(env.AldyBaggerBotUR3.robot.model.fkine(p), [env.AldyBaggerBotUR3.gripper.LeftFinger.getpos,env.AldyBaggerBotUR3.gripper.RightFinger.getpos]);
                    
                    p = [app.L1Slider_KUKA.Value, app.L2Slider_KUKA.Value, app.L3Slider_KUKA.Value, app.L4Slider_KUKA.Value, app.L5Slider_KUKA.Value, app.L6Slider_KUKA.Value, app.L7Slider_KUKA.Value];
                    p = deg2rad(p);
                    env.AldyBaggerBotKUKA.robot.model.animate(p);
                    env.AldyBaggerBotKUKA.gripper.moveGripper(env.AldyBaggerBotKUKA.robot.model.fkine(p), [env.AldyBaggerBotKUKA.gripper.LeftFinger.getpos,env.AldyBaggerBotKUKA.gripper.RightFinger.getpos]);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, env)
            app.env = env;
            app.bot1P = env.AldyBaggerBotUR3.robot.model.getpos;
            app.bot1T = env.AldyBaggerBotUR3.robot.model.fkine(app.bot1P);
            
            app.bot2P = env.AldyBaggerBotKUKA.robot.model.getpos;
            app.bot2T = env.AldyBaggerBotKUKA.robot.model.fkine(app.bot2P);
            
            %set slider limits
            limits = rad2deg(env.AldyBaggerBotUR3.robot.model.qlim);
            app.L1Slider_UR3.Limits = limits(1, :);
            app.L2Slider_UR3.Limits = limits(2, :);
            app.L3Slider_UR3.Limits = limits(3, :);
            app.L4Slider_UR3.Limits = limits(4, :);
            app.L5Slider_UR3.Limits = limits(5, :);
            app.L6Slider_UR3.Limits = limits(6, :);
            
            limits = rad2deg(env.AldyBaggerBotKUKA.robot.model.qlim);
            app.L1Slider_KUKA.Limits = limits(1, :);
            app.L2Slider_KUKA.Limits = limits(2, :);
            app.L3Slider_KUKA.Limits = limits(3, :);
            app.L4Slider_KUKA.Limits = limits(4, :);
            app.L5Slider_KUKA.Limits = limits(5, :);
            app.L6Slider_KUKA.Limits = limits(6, :);
            app.L7Slider_KUKA.Limits = limits(7, :);
            
            pDegrees = rad2deg(app.bot1P);
            app.L1Slider_UR3.Value = pDegrees(1);
            app.L2Slider_UR3.Value = pDegrees(2);
            app.L3Slider_UR3.Value = pDegrees(3);
            app.L4Slider_UR3.Value = pDegrees(4);
            app.L5Slider_UR3.Value = pDegrees(5);
            app.L6Slider_UR3.Value = pDegrees(6);
            
            pDegrees = rad2deg(app.bot2P);
            app.L1Slider_KUKA.Value = pDegrees(1);
            app.L2Slider_KUKA.Value = pDegrees(2);
            app.L3Slider_KUKA.Value = pDegrees(3);
            app.L4Slider_KUKA.Value = pDegrees(4);
            app.L5Slider_KUKA.Value = pDegrees(5);
            app.L6Slider_KUKA.Value = pDegrees(6);
            app.L7Slider_KUKA.Value = pDegrees(7);
            
            
        end

        % Button pushed function: eStopButton
        function eStopButtonPushed(app, event)
            if app.eStop == false
                app.eStopButton.BackgroundColor = [1,0,0];
                app.JoggingEnabledLamp.Color = [1,0,0];
                app.eStop = true;
                app.idle = true;
            else
                app.eStopButton.BackgroundColor = [0,1,0];
                app.eStop = false;
            end        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.eStop == false
                app.idle = false;
            end
        end

        % Button pushed function: L1plusButton_UR3
        function L1plusButton_UR3Pushed(app, event)
            app.L1Slider_UR3.Value = app.L1Slider_UR3.Value + app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L1minusButton_UR3
        function L1minusButton_UR3Pushed(app, event)
            app.L1Slider_UR3.Value = app.L1Slider_UR3.Value - app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L2plusButton_UR3
        function L2plusButton_UR3Pushed(app, event)
            app.L2Slider_UR3.Value = app.L2Slider_UR3.Value + app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L2minusButton_UR3
        function L2minusButton_UR3Pushed(app, event)
            app.L2Slider_UR3.Value = app.L2Slider_UR3.Value - app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L3plusButton_UR3
        function L3plusButton_UR3Pushed(app, event)
            app.L3Slider_UR3.Value = app.L3Slider_UR3.Value + app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L3minusButton_UR3
        function L3minusButton_UR3Pushed(app, event)
            app.L3Slider_UR3.Value = app.L3Slider_UR3.Value - app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L4plusButton_UR3
        function L4plusButton_UR3Pushed(app, event)
            app.L4Slider_UR3.Value = app.L4Slider_UR3.Value + app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L4minusButton_UR3
        function L4minusButton_UR3Pushed(app, event)
            app.L4Slider_UR3.Value = app.L4Slider_UR3.Value - app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L5plusButton_UR3
        function L5plusButton_UR3Pushed(app, event)
            app.L5Slider_UR3.Value = app.L5Slider_UR3.Value + app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L5minusButton_UR3
        function L5minusButton_UR3Pushed(app, event)
            app.L5Slider_UR3.Value = app.L5Slider_UR3.Value - app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L6plusButton_UR3
        function L6plusButton_UR3Pushed(app, event)
            app.L6Slider_UR3.Value = app.L6Slider_UR3.Value + app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: L6minusButton_UR3
        function L6minusButton_UR3Pushed(app, event)
            app.L6Slider_UR3.Value = app.L6Slider_UR3.Value - app.LinkJogStepdegEditField_UR3.Value;
        end

        % Button pushed function: YplusButton_UR3
        function YplusButton_UR3Pushed(app, event)
            newT = app.bot1T;
            newT(2, 4) = newT(2, 4) + app.XYZJogStepmmEditField_UR3.Value/1000;
            try newP = app.env.AldyBaggerBotUR3.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider_UR3.Value = rad2deg(newP(1));
                app.L2Slider_UR3.Value = rad2deg(newP(2));
                app.L3Slider_UR3.Value = rad2deg(newP(3));
                app.L4Slider_UR3.Value = rad2deg(newP(4));
                app.L5Slider_UR3.Value = rad2deg(newP(5));
                app.L6Slider_UR3.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: YminusButton_UR3
        function YminusButton_UR3Pushed(app, event)
            newT = app.bot1T;
            newT(2, 4) = newT(2, 4) - app.XYZJogStepmmEditField_UR3.Value/1000;
            try newP = app.env.AldyBaggerBotUR3.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider_UR3.Value = rad2deg(newP(1));
                app.L2Slider_UR3.Value = rad2deg(newP(2));
                app.L3Slider_UR3.Value = rad2deg(newP(3));
                app.L4Slider_UR3.Value = rad2deg(newP(4));
                app.L5Slider_UR3.Value = rad2deg(newP(5));
                app.L6Slider_UR3.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: XminusButton_UR3
        function XminusButton_UR3Pushed(app, event)
            newT = app.bot1T;
            newT(1, 4) = newT(1, 4) - app.XYZJogStepmmEditField_UR3.Value/1000;
            try newP = app.env.AldyBaggerBotUR3.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider_UR3.Value = rad2deg(newP(1));
                app.L2Slider_UR3.Value = rad2deg(newP(2));
                app.L3Slider_UR3.Value = rad2deg(newP(3));
                app.L4Slider_UR3.Value = rad2deg(newP(4));
                app.L5Slider_UR3.Value = rad2deg(newP(5));
                app.L6Slider_UR3.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: XplusButton_UR3
        function XplusButton_UR3Pushed(app, event)
            newT = app.bot1T;
            newT(1, 4) = newT(1, 4) + app.XYZJogStepmmEditField_UR3.Value/1000;
            try newP = app.env.AldyBaggerBotUR3.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider_UR3.Value = rad2deg(newP(1));
                app.L2Slider_UR3.Value = rad2deg(newP(2));
                app.L3Slider_UR3.Value = rad2deg(newP(3));
                app.L4Slider_UR3.Value = rad2deg(newP(4));
                app.L5Slider_UR3.Value = rad2deg(newP(5));
                app.L6Slider_UR3.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: ZplusButton_UR3
        function ZplusButton_UR3Pushed(app, event)
            newT = app.bot1T;
            newT(3, 4) = newT(3, 4) + app.XYZJogStepmmEditField_UR3.Value/1000;
            try newP = app.env.AldyBaggerBotUR3.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider_UR3.Value = rad2deg(newP(1));
                app.L2Slider_UR3.Value = rad2deg(newP(2));
                app.L3Slider_UR3.Value = rad2deg(newP(3));
                app.L4Slider_UR3.Value = rad2deg(newP(4));
                app.L5Slider_UR3.Value = rad2deg(newP(5));
                app.L6Slider_UR3.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: ZminusButton_UR3
        function ZminusButton_UR3Pushed(app, event)
            newT = app.bot1T;
            newT(3, 4) = newT(3, 4) - app.XYZJogStepmmEditField_UR3.Value/1000;
            try newP = app.env.AldyBaggerBotUR3.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider_UR3.Value = rad2deg(newP(1));
                app.L2Slider_UR3.Value = rad2deg(newP(2));
                app.L3Slider_UR3.Value = rad2deg(newP(3));
                app.L4Slider_UR3.Value = rad2deg(newP(4));
                app.L5Slider_UR3.Value = rad2deg(newP(5));
                app.L6Slider_UR3.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: personYplusButton
        function personYplusButtonPushed(app, event)
            app.env.Person.body.base(2, 4) = app.env.Person.body.base(2, 4) + app.PersonStepmmEditField.Value/1000;
        end

        % Button pushed function: personXminusButton
        function personXminusButtonPushed(app, event)
            app.env.Person.body.base(1, 4) = app.env.Person.body.base(1, 4) - app.PersonStepmmEditField.Value/1000;
        end

        % Button pushed function: personXplusButton
        function personXplusButtonPushed(app, event)
            app.env.Person.body.base(1, 4) = app.env.Person.body.base(1, 4) + app.PersonStepmmEditField.Value/1000;
        end

        % Button pushed function: personYminusButton
        function personYminusButtonPushed(app, event)
            app.env.Person.body.base(2, 4) = app.env.Person.body.base(2, 4) - app.PersonStepmmEditField.Value/1000;
        end

        % Button pushed function: L1plusButton_KUKA
        function L1plusButton_KUKAPushed(app, event)
            app.L1Slider_KUKA.Value = app.L1Slider_KUKA.Value + app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L1minusButton_KUKA
        function L1minusButton_KUKAPushed(app, event)
            app.L1Slider_KUKA.Value = app.L1Slider_KUKA.Value - app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L2plusButton_KUKA
        function L2plusButton_KUKAPushed(app, event)
            app.L2Slider_KUKA.Value = app.L2Slider_KUKA.Value + app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L2minusButton_KUKA
        function L2minusButton_KUKAPushed(app, event)
            app.L2Slider_KUKA.Value = app.L2Slider_KUKA.Value - app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L3plusButton_KUKA
        function L3plusButton_KUKAPushed(app, event)
            app.L3Slider_KUKA.Value = app.L3Slider_KUKA.Value + app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L3minusButton_KUKA
        function L3minusButton_KUKAPushed(app, event)
            app.L3Slider_KUKA.Value = app.L3Slider_KUKA.Value - app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L4plusButton_KUKA
        function L4plusButton_KUKAPushed(app, event)
            app.L4Slider_KUKA.Value = app.L4Slider_KUKA.Value + app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L4minusButton_KUKA
        function L4minusButton_KUKAPushed(app, event)
            app.L4Slider_KUKA.Value = app.L4Slider_KUKA.Value - app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L5plusButton_KUKA
        function L5plusButton_KUKAPushed(app, event)
            app.L5Slider_KUKA.Value = app.L5Slider_KUKA.Value + app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L5minusButton_KUKA
        function L5minusButton_KUKAPushed(app, event)
            app.L5Slider_KUKA.Value = app.L5Slider_KUKA.Value - app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L6plusButton_KUKA
        function L6plusButton_KUKAPushed(app, event)
            app.L6Slider_KUKA.Value = app.L6Slider_KUKA.Value + app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L6minusButton_KUKA
        function L6minusButton_KUKAPushed(app, event)
            app.L6Slider_KUKA.Value = app.L6Slider_KUKA.Value - app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L7plusButton_KUKA
        function L7plusButton_KUKAPushed(app, event)
            app.L7Slider_KUKA.Value = app.L7Slider_KUKA.Value + app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: L7minusButton_KUKA
        function L7minusButton_KUKAPushed(app, event)
            app.L7Slider_KUKA.Value = app.L7Slider_KUKA.Value - app.LinkJogStepdegEditField_KUKA.Value;
        end

        % Button pushed function: YplusButton_KUKA
        function YplusButton_KUKAPushed(app, event)
            newT = app.bot2T;
            newT(2, 4) = newT(2, 4) + app.XYZJogStepmmEditField_KUKA.Value/1000;
            try newP = app.env.AldyBaggerBotKUKA.robot.model.ikcon(newT, app.bot2P);
                app.L1Slider_KUKA.Value = rad2deg(newP(1));
                app.L2Slider_KUKA.Value = rad2deg(newP(2));
                app.L3Slider_KUKA.Value = rad2deg(newP(3));
                app.L4Slider_KUKA.Value = rad2deg(newP(4));
                app.L5Slider_KUKA.Value = rad2deg(newP(5));
                app.L6Slider_KUKA.Value = rad2deg(newP(6));
                app.L7Slider_KUKA.Value = rad2deg(newP(7));
            catch ME
                
            end
        end

        % Button pushed function: XminusButton_KUKA
        function XminusButton_KUKAPushed(app, event)
            newT = app.bot2T;
            newT(1, 4) = newT(1, 4) - app.XYZJogStepmmEditField_KUKA.Value/1000;
            try newP = app.env.AldyBaggerBotKUKA.robot.model.ikcon(newT, app.bot2P);
                app.L1Slider_KUKA.Value = rad2deg(newP(1));
                app.L2Slider_KUKA.Value = rad2deg(newP(2));
                app.L3Slider_KUKA.Value = rad2deg(newP(3));
                app.L4Slider_KUKA.Value = rad2deg(newP(4));
                app.L5Slider_KUKA.Value = rad2deg(newP(5));
                app.L6Slider_KUKA.Value = rad2deg(newP(6));
                app.L7Slider_KUKA.Value = rad2deg(newP(7));
            catch ME
                
            end
        end

        % Button pushed function: XplusButton_KUKA
        function XplusButton_KUKAPushed(app, event)
            newT = app.bot2T;
            newT(1, 4) = newT(1, 4) + app.XYZJogStepmmEditField_KUKA.Value/1000;
            try newP = app.env.AldyBaggerBotKUKA.robot.model.ikcon(newT, app.bot2P);
                app.L1Slider_KUKA.Value = rad2deg(newP(1));
                app.L2Slider_KUKA.Value = rad2deg(newP(2));
                app.L3Slider_KUKA.Value = rad2deg(newP(3));
                app.L4Slider_KUKA.Value = rad2deg(newP(4));
                app.L5Slider_KUKA.Value = rad2deg(newP(5));
                app.L6Slider_KUKA.Value = rad2deg(newP(6));
                app.L7Slider_KUKA.Value = rad2deg(newP(7));
            catch ME
                
            end
        end

        % Button pushed function: YminusButton_KUKA
        function YminusButton_KUKAPushed(app, event)
            newT = app.bot2T;
            newT(2, 4) = newT(2, 4) - app.XYZJogStepmmEditField_KUKA.Value/1000;
            try newP = app.env.AldyBaggerBotKUKA.robot.model.ikcon(newT, app.bot2P);
                app.L1Slider_KUKA.Value = rad2deg(newP(1));
                app.L2Slider_KUKA.Value = rad2deg(newP(2));
                app.L3Slider_KUKA.Value = rad2deg(newP(3));
                app.L4Slider_KUKA.Value = rad2deg(newP(4));
                app.L5Slider_KUKA.Value = rad2deg(newP(5));
                app.L6Slider_KUKA.Value = rad2deg(newP(6));
                app.L7Slider_KUKA.Value = rad2deg(newP(7));
            catch ME
                
            end
        end

        % Button pushed function: ZplusButton_KUKA
        function ZplusButton_KUKAPushed(app, event)
            newT = app.bot2T;
            newT(3, 4) = newT(3, 4) + app.XYZJogStepmmEditField_KUKA.Value/1000;
            try newP = app.env.AldyBaggerBotKUKA.robot.model.ikcon(newT, app.bot2P);
                app.L1Slider_KUKA.Value = rad2deg(newP(1));
                app.L2Slider_KUKA.Value = rad2deg(newP(2));
                app.L3Slider_KUKA.Value = rad2deg(newP(3));
                app.L4Slider_KUKA.Value = rad2deg(newP(4));
                app.L5Slider_KUKA.Value = rad2deg(newP(5));
                app.L6Slider_KUKA.Value = rad2deg(newP(6));
                app.L7Slider_KUKA.Value = rad2deg(newP(7));
            catch ME
                
            end
        end

        % Button pushed function: ZminusButton_KUKA
        function ZminusButton_KUKAPushed(app, event)
            newT = app.bot2T;
            newT(3, 4) = newT(3, 4) - app.XYZJogStepmmEditField_KUKA.Value/1000;
            try newP = app.env.AldyBaggerBotKUKA.robot.model.ikcon(newT, app.bot2P);
                app.L1Slider_KUKA.Value = rad2deg(newP(1));
                app.L2Slider_KUKA.Value = rad2deg(newP(2));
                app.L3Slider_KUKA.Value = rad2deg(newP(3));
                app.L4Slider_KUKA.Value = rad2deg(newP(4));
                app.L5Slider_KUKA.Value = rad2deg(newP(5));
                app.L6Slider_KUKA.Value = rad2deg(newP(6));
                app.L7Slider_KUKA.Value = rad2deg(newP(7));
            catch ME
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.JoggingEnabledLampLabel = uilabel(app.UIFigure);
            app.JoggingEnabledLampLabel.Text = 'Jogging Enabled';
            app.JoggingEnabledLamp = uilamp(app.UIFigure);
            app.eStopButton = uibutton(app.UIFigure, 'push');
            app.eStopButton.ButtonPushedFcn = createCallbackFcn(app, @eStopButtonPushed, true);
            app.eStopButton.Icon = 'eStop_Icon.png';
            app.eStopButton.Text = '';
            app.ModeSwitchLabel = uilabel(app.UIFigure);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ModeSwitch.Items = {'Normal', 'Programming'};
            app.ModeSwitch.Value = 'Normal';
            app.L1Slider_UR3 = uislider(app.UIFigure);
            app.L2Slider_UR3 = uislider(app.UIFigure);
            app.L3Slider_UR3 = uislider(app.UIFigure);
            app.L4Slider_UR3 = uislider(app.UIFigure);
            app.L5Slider_UR3 = uislider(app.UIFigure);
            app.L6Slider_UR3 = uislider(app.UIFigure);
            app.YplusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.YplusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @YplusButton_UR3Pushed, true);
            app.YplusButton_UR3.Text = '+Y';
            app.XminusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.XminusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @XminusButton_UR3Pushed, true);
            app.XminusButton_UR3.Text = '-X';
            app.XplusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.XplusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @XplusButton_UR3Pushed, true);
            app.XplusButton_UR3.Text = '+X';
            app.YminusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.YminusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @YminusButton_UR3Pushed, true);
            app.YminusButton_UR3.Text = '-Y';
            app.ZplusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.ZplusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @ZplusButton_UR3Pushed, true);
            app.ZplusButton_UR3.Text = '+Z';
            app.ZminusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.ZminusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @ZminusButton_UR3Pushed, true);
            app.ZminusButton_UR3.Text = '-Z';
            app.UR3RobotTransformLabel = uilabel(app.UIFigure);
            app.UR3RobotTransformLabel.Text = 'UR3 Robot Transform';
            app.YLabel_UR3 = uilabel(app.UIFigure);
            app.YLabel_UR3.Text = 'Y:';
            app.XLabel_UR3 = uilabel(app.UIFigure);
            app.XLabel_UR3.Text = 'X:';
            app.ZLabel_UR3 = uilabel(app.UIFigure);
            app.ZLabel_UR3.Text = 'Z:';
            app.robotTransformY_UR3 = uilabel(app.UIFigure);
            app.robotTransformY_UR3.Text = '0.000';
            app.robotTransformX_UR3 = uilabel(app.UIFigure);
            app.robotTransformX_UR3.Text = '0.000';
            app.robotTransformZ_UR3 = uilabel(app.UIFigure);
            app.robotTransformZ_UR3.Text = '0.000';
            app.PitchLabel_UR3 = uilabel(app.UIFigure);
            app.PitchLabel_UR3.Text = 'P:';
            app.RollLabel_UR3 = uilabel(app.UIFigure);
            app.RollLabel_UR3.Text = 'R:';
            app.YawLabel_UR3 = uilabel(app.UIFigure);
            app.YawLabel_UR3.Text = 'Y:';
            app.robotTransformPitch_UR3 = uilabel(app.UIFigure);
            app.robotTransformPitch_UR3.Text = '0.000';
            app.robotTransformRoll_UR3 = uilabel(app.UIFigure);
            app.robotTransformRoll_UR3.Text = '0.000';
            app.robotTransformYaw_UR3 = uilabel(app.UIFigure);
            app.robotTransformYaw_UR3.Text = '0.000';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.L1plusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L1plusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L1plusButton_UR3Pushed, true);
            app.L1plusButton_UR3.Text = 'L1+';
            app.L1minusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L1minusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L1minusButton_UR3Pushed, true);
            app.L1minusButton_UR3.Text = 'L1-';
            app.L2plusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L2plusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L2plusButton_UR3Pushed, true);
            app.L2plusButton_UR3.Text = 'L2+';
            app.L2minusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L2minusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L2minusButton_UR3Pushed, true);
            app.L2minusButton_UR3.Text = 'L2-';
            app.L3plusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L3plusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L3plusButton_UR3Pushed, true);
            app.L3plusButton_UR3.Text = 'L3+';
            app.L3minusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L3minusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L3minusButton_UR3Pushed, true);
            app.L3minusButton_UR3.Text = 'L3-';
            app.L4plusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L4plusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L4plusButton_UR3Pushed, true);
            app.L4plusButton_UR3.Text = 'L4+';
            app.L4minusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L4minusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L4minusButton_UR3Pushed, true);
            app.L4minusButton_UR3.Text = 'L4-';
            app.L5plusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L5plusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L5plusButton_UR3Pushed, true);
            app.L5plusButton_UR3.Text = 'L5+';
            app.L5minusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L5minusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L5minusButton_UR3Pushed, true);
            app.L5minusButton_UR3.Text = 'L5-';
            app.L6plusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L6plusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L6plusButton_UR3Pushed, true);
            app.L6plusButton_UR3.Text = 'L6+';
            app.L6minusButton_UR3 = uibutton(app.UIFigure, 'push');
            app.L6minusButton_UR3.ButtonPushedFcn = createCallbackFcn(app, @L6minusButton_UR3Pushed, true);
            app.L6minusButton_UR3.Text = 'L6-';
            app.LinkJogStepdegEditFieldLabel = uilabel(app.UIFigure);
            app.LinkJogStepdegEditFieldLabel.Text = 'Link Jog Step (deg):';
            app.LinkJogStepdegEditField_UR3 = uieditfield(app.UIFigure, 'numeric');
            app.LinkJogStepdegEditField_UR3.Value = 5;
            app.XYZJogStepmmEditFieldLabel = uilabel(app.UIFigure);
            app.XYZJogStepmmEditFieldLabel.Text = 'XYZ Jog Step (mm):';
            app.XYZJogStepmmEditField_UR3 = uieditfield(app.UIFigure, 'numeric');
            app.XYZJogStepmmEditField_UR3.Value = 5;
            app.personYplusButton = uibutton(app.UIFigure, 'push');
            app.personYplusButton.ButtonPushedFcn = createCallbackFcn(app, @personYplusButtonPushed, true);
            app.personYplusButton.Text = '+Y';
            app.personXminusButton = uibutton(app.UIFigure, 'push');
            app.personXminusButton.ButtonPushedFcn = createCallbackFcn(app, @personXminusButtonPushed, true);
            app.personXminusButton.Text = '-X';
            app.personXplusButton = uibutton(app.UIFigure, 'push');
            app.personXplusButton.ButtonPushedFcn = createCallbackFcn(app, @personXplusButtonPushed, true);
            app.personXplusButton.Text = '+X';
            app.personYminusButton = uibutton(app.UIFigure, 'push');
            app.personYminusButton.ButtonPushedFcn = createCallbackFcn(app, @personYminusButtonPushed, true);
            app.personYminusButton.Text = '-Y';
            app.PersonControlsLabel = uilabel(app.UIFigure);
            app.PersonControlsLabel.Text = 'Person Controls';
            app.PersonStepmmEditFieldLabel = uilabel(app.UIFigure);
            app.PersonStepmmEditFieldLabel.Text = 'Person Step (mm):';
            app.PersonStepmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PersonStepmmEditField.Value = 10;
            app.LightCurtainSwitchLabel = uilabel(app.UIFigure);
            app.LightCurtainSwitchLabel.Text = 'Light Curtain';
            app.LightCurtainSwitch = uiswitch(app.UIFigure, 'slider');
            app.LightCurtainSwitch.Items = {'Disabled', 'Enabled'};
            app.LightCurtainSwitch.Value = 'Enabled';
            app.L1Slider_KUKA = uislider(app.UIFigure);
            app.L2Slider_KUKA = uislider(app.UIFigure);
            app.L3Slider_KUKA = uislider(app.UIFigure);
            app.L4Slider_KUKA = uislider(app.UIFigure);
            app.L5Slider_KUKA = uislider(app.UIFigure);
            app.L6Slider_KUKA = uislider(app.UIFigure);
            app.YplusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.YplusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @YplusButton_KUKAPushed, true);
            app.YplusButton_KUKA.Text = '+Y';
            app.XminusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.XminusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @XminusButton_KUKAPushed, true);
            app.XminusButton_KUKA.Text = '-X';
            app.XplusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.XplusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @XplusButton_KUKAPushed, true);
            app.XplusButton_KUKA.Text = '+X';
            app.YminusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.YminusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @YminusButton_KUKAPushed, true);
            app.YminusButton_KUKA.Text = '-Y';
            app.ZplusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.ZplusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @ZplusButton_KUKAPushed, true);
            app.ZplusButton_KUKA.Text = '+Z';
            app.ZminusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.ZminusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @ZminusButton_KUKAPushed, true);
            app.ZminusButton_KUKA.Text = '-Z';
            app.KUKARobotTransformLabel = uilabel(app.UIFigure);
            app.KUKARobotTransformLabel.Text = 'KUKA Robot Transform';
            app.YLabel_KUKA = uilabel(app.UIFigure);
            app.YLabel_KUKA.Text = 'Y:';
            app.XLabel_KUKA = uilabel(app.UIFigure);
            app.XLabel_KUKA.Text = 'X:';
            app.ZLabel_KUKA = uilabel(app.UIFigure);
            app.ZLabel_KUKA.Text = 'Z:';
            app.robotTransformY_KUKA = uilabel(app.UIFigure);
            app.robotTransformY_KUKA.Text = '0.000';
            app.robotTransformX_KUKA = uilabel(app.UIFigure);
            app.robotTransformX_KUKA.Text = '0.000';
            app.robotTransformZ_KUKA = uilabel(app.UIFigure);
            app.robotTransformZ_KUKA.Text = '0.000';
            app.PitchLabel_KUKA = uilabel(app.UIFigure);
            app.PitchLabel_KUKA.Text = 'P:';
            app.RollLabel_KUKA = uilabel(app.UIFigure);
            app.RollLabel_KUKA.Text = 'R:';
            app.YawLabel_KUKA = uilabel(app.UIFigure);
            app.YawLabel_KUKA.Text = 'Y:';
            app.robotTransformPitch_KUKA = uilabel(app.UIFigure);
            app.robotTransformPitch_KUKA.Text = '0.000';
            app.robotTransformRoll_KUKA = uilabel(app.UIFigure);
            app.robotTransformRoll_KUKA.Text = '0.000';
            app.robotTransformYaw_KUKA = uilabel(app.UIFigure);
            app.robotTransformYaw_KUKA.Text = '0.000';
            app.L1plusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L1plusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L1plusButton_KUKAPushed, true);
            app.L1plusButton_KUKA.Text = 'L1+';
            app.L1minusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L1minusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L1minusButton_KUKAPushed, true);
            app.L1minusButton_KUKA.Text = 'L1-';
            app.L2plusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L2plusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L2plusButton_KUKAPushed, true);
            app.L2plusButton_KUKA.Text = 'L2+';
            app.L2minusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L2minusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L2minusButton_KUKAPushed, true);
            app.L2minusButton_KUKA.Text = 'L2-';
            app.L3plusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L3plusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L3plusButton_KUKAPushed, true);
            app.L3plusButton_KUKA.Text = 'L3+';
            app.L3minusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L3minusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L3minusButton_KUKAPushed, true);
            app.L3minusButton_KUKA.Text = 'L3-';
            app.L4plusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L4plusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L4plusButton_KUKAPushed, true);
            app.L4plusButton_KUKA.Text = 'L4+';
            app.L4minusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L4minusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L4minusButton_KUKAPushed, true);
            app.L4minusButton_KUKA.Text = 'L4-';
            app.L5plusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L5plusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L5plusButton_KUKAPushed, true);
            app.L5plusButton_KUKA.Text = 'L5+';
            app.L5minusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L5minusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L5minusButton_KUKAPushed, true);
            app.L5minusButton_KUKA.Text = 'L5-';
            app.L6plusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L6plusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L6plusButton_KUKAPushed, true);
            app.L6plusButton_KUKA.Text = 'L6+';
            app.L6minusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L6minusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L6minusButton_KUKAPushed, true);
            app.L6minusButton_KUKA.Text = 'L6-';
            app.LinkJogStepdegEditField_2Label = uilabel(app.UIFigure);
            app.LinkJogStepdegEditField_2Label.Text = 'Link Jog Step (deg):';
            app.LinkJogStepdegEditField_KUKA = uieditfield(app.UIFigure, 'numeric');
            app.LinkJogStepdegEditField_KUKA.Value = 5;
            app.XYZJogStepmmEditField_2Label = uilabel(app.UIFigure);
            app.XYZJogStepmmEditField_2Label.Text = 'XYZ Jog Step (mm):';
            app.XYZJogStepmmEditField_KUKA = uieditfield(app.UIFigure, 'numeric');
            app.XYZJogStepmmEditField_KUKA.Value = 5;
            app.L7Slider_KUKA = uislider(app.UIFigure);
            app.L7plusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L7plusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L7plusButton_KUKAPushed, true);
            app.L7plusButton_KUKA.Text = 'L7+';
            app.L7minusButton_KUKA = uibutton(app.UIFigure, 'push');
            app.L7minusButton_KUKA.ButtonPushedFcn = createCallbackFcn(app, @L7minusButton_KUKAPushed, true);
            app.L7minusButton_KUKA.Text = 'L7-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AldyGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end